classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        AddHeatExchangerButton  matlab.ui.control.Button
        HeatExchangerLabel      matlab.ui.control.Label
        ListofHEListBox         matlab.ui.control.ListBox
        ListofHEListBoxLabel    matlab.ui.control.Label
        RightPanel              matlab.ui.container.Panel
        ClearPlotButton         matlab.ui.control.Button
        SimulationButton        matlab.ui.control.Button
        ExportButton            matlab.ui.control.Button
        SimulationLabel         matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
         HeatExchangers = HeatExchanger.empty(0, 1);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulationButton
        function SimulationButtonPushed(app, event)
          % Get the selected heat exchangers from the list
            selectedHEIndices = app.ListofHEListBox.Value;
            if ischar(app.ListofHEListBox.Value)
                selectedHEIndices = {app.ListofHEListBox.Value};
            else
                selectedHEIndices = app.ListofHEListBox.Value;
            end
            % Convert the cell array of strings to a numeric array
            selectedHEIndices = cellfun(@str2double, selectedHEIndices);

            selectedHEs = app.HeatExchangers(selectedHEIndices);
            
            % Call the CalcHE function to perform the simulation
            [X, T] = CalcHE(selectedHEs);

            % Clear the UIAxes
            cla(app.UIAxes);

            % Plot the simulation results
            plot(app.UIAxes, X, T);

            % Add labels and title to the plot
            xlabel(app.UIAxes, 'Area (m²)');
            ylabel(app.UIAxes, 'Temperature (K)');
            title(app.UIAxes, 'Temperature Profile');

            % Update the UIAxes limits and aspect ratio
            xlim(app.UIAxes, [0 max(X)]);
            ylim(app.UIAxes, [min(T) max(T)]);
            daspect(app.UIAxes, [1, 1, 1]);
        end

        % Button pushed function: AddHeatExchangerButton
        function AddHeatExchangerButtonPushed(app, event)
           % Open a dialog or form for users to input heat exchanger parameters
            % and create a new HeatExchanger object
            prompt = {'Number of Pipes:', 'Thickness of Pipes:', 'Diameter of Pipes:', ...
                      'Thermal Conductivity of Pipes:', 'Length:', 'Position:', ...
                      'Mass Flow Rate:', 'Temperature:', 'Density:', ...
                      'Thermal Conductivity:', 'Specific Heat Capacity:'};
            dlgtitle = 'Create Heat Exchanger';
            defaultValues = {'', '', '', '', '', '', '', '', '', '', ''};
            userInput = inputdlg(prompt, dlgtitle, [1, 50], defaultValues);

            % Convert user input to appropriate data types
            userInput = cellfun(@str2double, userInput, 'UniformOutput', false);

            % Check if any input values are empty or invalid
            if any(cellfun(@isnan, userInput))
                errordlg('Invalid input. Please enter valid numeric values for all fields.', 'Invalid Input');
                return;
            end

            % Create a new HeatExchanger object with the user-provided parameters
            newHE = HeatExchanger(userInput{1}, userInput{2}, userInput{3}, userInput{4}, ...
                                  userInput{5}, userInput{6}, userInput{7}, ...
                                  userInput{8}, userInput{9}, userInput{10}, ...
                                  userInput{11});

            % Add the new heat exchanger to the list
            app.HeatExchangers(end+1) = newHE;

            % Update the ListBox items and select the newly added heat exchanger
            app.ListofHEListBox.Items = num2str((1:numel(app.HeatExchangers)).');
            app.ListofHEListBox.Value = numel(app.HeatExchangers);
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            cla(app.UIAxes); % Clear the plot on the UIAxes
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % Get the data from the UIAxes
            XData = app.UIAxes.Children.XData;
            YData = app.UIAxes.Children.YData;

            % Combine the data into a table for export
            dataTable = table(XData.', YData.', 'VariableNames', {'Area', 'Temperature'});

            % Open a save dialog for the user to choose where to save the data
            [fileName, filePath] = uiputfile('*.csv', 'Save Data As');

            % Check if the user clicked 'Cancel'
            if isequal(fileName, 0) || isequal(filePath, 0)
                return;
            end

            % Write the data to the selected file
            writetable(dataTable, fullfile(filePath, fileName));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {631, 631};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {198, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ListofHEListBoxLabel = uilabel(app.LeftPanel);
            app.ListofHEListBoxLabel.Text = 'List of HE';
            app.ListofHEListBox = uilistbox(app.LeftPanel);
            app.ListofHEListBox.Items = {'Item 1'};
            app.ListofHEListBox.Multiselect = 'on';
            app.ListofHEListBox.Value = {'Item 1'};
            app.HeatExchangerLabel = uilabel(app.LeftPanel);
            app.HeatExchangerLabel.Text = 'Heat Exchanger';
            app.AddHeatExchangerButton = uibutton(app.LeftPanel, 'push');
            app.AddHeatExchangerButton.ButtonPushedFcn = createCallbackFcn(app, @AddHeatExchangerButtonPushed, true);
            app.AddHeatExchangerButton.Text = 'Add Heat Exchanger';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Result')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SimulationLabel = uilabel(app.RightPanel);
            app.SimulationLabel.Text = 'Simulation';
            app.ExportButton = uibutton(app.RightPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.SimulationButton = uibutton(app.RightPanel, 'push');
            app.SimulationButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationButtonPushed, true);
            app.SimulationButton.Text = 'Simulation';
            app.ClearPlotButton = uibutton(app.RightPanel, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end